classdef DFSDataVisualisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DFSMatlabUIFigure  matlab.ui.Figure
        Slider             matlab.ui.control.Slider
        SliderLabel        matlab.ui.control.Label
        DFSScatter         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Colours and Shapes
        colours % Description: stores colours
        depth % Description: stores depth
        x % Description: stores x colours
        y % Description: stores y colours

    end
    
    methods (Access = private)
        function PlotToGraph(app, aDepth)
          for i = 1:size(app.depth, 1)
              if app.depth(i) == aDepth
                  app.colours(i, :) = [0, 0, 0];
              else
                  app.colours(i, :) = [0.18, 0.67, 0.59];
              end
          end
          scatter(app.DFSScatter, app.x, app.y, 36, app.colours, "filled");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("cvs/")
            dfs = readtable("cvs/DFS.csv");
            app.depth = uint8(dfs{:, "D"}); % maybe delete this depth
            app.x = dfs{:, "X"};
            app.y = dfs{:, "Y"};
            
            % default
            app.SliderLabel.Text = "Depth";

            app.Slider.Limits = [1 size(app.depth, 1)];
            app.Slider.MajorTicks = 1:size(app.depth, 1);

            app.PlotToGraph(1);
        end

        % Value changed function: Slider
        function sliderSnapToTick(app, event)
            app.Slider.Value = round(event.Value);
            app.PlotToGraph(app.Slider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DFSMatlabUIFigure = uifigure('Visible', 'off');
            app.DFSMatlabUIFigure.Name = 'DFS Matlab';
            app.DFSScatter = uiaxes(app.DFSMatlabUIFigure);
            title(app.DFSScatter, 'Depth First Search')
            xlabel(app.DFSScatter, 'X')
            ylabel(app.DFSScatter, 'Y')
            zlabel(app.DFSScatter, 'Z')
            colormap(app.DFSScatter, 'winter')
            app.SliderLabel = uilabel(app.DFSMatlabUIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.DFSMatlabUIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @sliderSnapToTick, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.DFSMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DFSDataVisualisation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DFSMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DFSMatlabUIFigure)
        end
    end
end