classdef KMeansDataVisualisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Slider         matlab.ui.control.Slider
        SliderLabel    matlab.ui.control.Label
        KMeansScatter  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Colours and Shapes
        kColours % Description: stores k colours
        kShapes % Description: stores shapes
        %KCluster
        depth % Description: enables the depth to be stored.
        dIndex % Description: enables the depth index to be stored.
        kIndex % Description: enables the kindex values to be stored.
        kX % Description: enables the x values to be stored.
        kY % Description: enables the y values to be stored.

        %clusters
        cDIndex % Cluster depth values
        cKIndex % Cluster data owners of which k values
        studentNumber % stores student number values
        cX % stores cluster x values
        cY % stores cluster y values
    end
    
    methods (Access = private)
        
        function PlotToGraph(app, depth)
            cog = [];
            for i = 1:size(app.dIndex, 1)
                if app.dIndex(i) == depth
                    j = size(cog, 1) + 1
                    cog(j,1) = app.kX(i);
                    cog(j,2) = app.kY(i);
                end
            end
            
            cluster = [];
            clKIndex = [];
            for i = 1:size(app.cDIndex, 1)
                if app.cDIndex(i) == depth
                    j = size(cluster, 1) + 1
                    cluster(j,1) = app.cX(i);
                    cluster(j,2) = app.cY(i);
                    clKIndex(j, 1) = app.cKIndex(i);
                end
            end

            app.Plot(cog, cluster, clKIndex)
        end
        
        function Plot(app, cog, cluster, clKIndex)
            combination = cluster;
            colours = [];
            %To be honest, ran out of time but what needed doing here was
            %to fill in different colours based on the the K. Would be nice
            %if I could get the Ks to be square and rest filled. However,
            %due to no time to research.
            for i = 1:size(combination, 1)
                for j = 1:size(app.kIndex, 1)
                    if clKIndex(i) == app.kIndex(j)
                        colours(i, :) = app.kColours(j,:);
                    end
                end
            end
            
            for i = 1:size(cog, 1)
                x = [0.35,0.15,0.50];
                
                x(1) = app.kColours(i, 1) * x(1);
                x(2) = app.kColours(i, 2) * x(2);
                x(3) = app.kColours(i, 3) * x(3);

                colours(size(colours, 1) + 1, :) = x;
                combination(size(combination, 1) + 1, :) = cog(i, :);
            end
            
            scatter(app.KMeansScatter, combination(:,1), combination(:,2), 36, colours, "filled");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("cvs/")
            kcluster = readtable("cvs/KCluster.csv");
            app.depth = uint8(unique(kcluster.D)); % maybe delete this depth
            app.dIndex = uint8(kcluster{:, "D"});
            app.kIndex = kcluster{:, "K"};
            app.kX = kcluster{:, "X"};
            app.kY = kcluster{:, "Y"};

            app.kColours = []
            for i = 1:size(app.kIndex, 1)
                for j = 1:3
                    app.kColours(i,j) = randn();
                end
            end


            % default
            app.SliderLabel.Text = "Depth";

            app.Slider.Value = 0;
            app.Slider.Limits = [0 size(app.depth, 1)-1];
            app.Slider.MajorTicks = 0:size(app.depth, 1)-1;

            % set up the graph
            cluster = readtable("cvs/Cluster.csv");
            app.cDIndex = uint8(cluster{:, "D"});
            app.cKIndex = uint8(cluster{:, "K"});
            app.studentNumber = string(cluster{:, 3});
            app.cX = cluster{:, "X"};
            app.cY = cluster{:, "Y"};

            app.PlotToGraph(0);
        end

        % Value changed function: Slider
        function sliderSnapToTick(app, event)
            app.Slider.Value = round(event.Value);
            app.PlotToGraph(app.Slider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KMeansScatter = uiaxes(app.UIFigure);
            title(app.KMeansScatter, 'KMeans - Students')
            xlabel(app.KMeansScatter, 'X')
            ylabel(app.KMeansScatter, 'Y')
            zlabel(app.KMeansScatter, 'Z')
            colormap(app.KMeansScatter, 'winter')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @sliderSnapToTick, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KMeansDataVisualisation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end